classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UITable                     matlab.ui.control.Table
        BreakpointsEditField        matlab.ui.control.NumericEditField
        BreakpointsEditFieldLabel   matlab.ui.control.Label
        GenerateButton              matlab.ui.control.Button
        EndtimeEditField            matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel       matlab.ui.control.Label
        StarttimeEditField          matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel     matlab.ui.control.Label
        SamplingrateEditField       matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        positions % Description
        samplingFrequency % Description
        startTime % Description
        endTime % Description
        breakpointsNumber % Description
        Property6 % Description
        Property7 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            start = app.StarttimeEditField.Value;
            endd = app.EndtimeEditField.Value;
            sample_rate = app.StarttimeEditField.Value;
            t = linspace(start,endd,(endd - start)*sample_rate );
            
            app.positions = sort(get(app.UITable, 'data'))';
            
            assignin("base", "p", app.positions);
            
        end

        % Value changed function: BreakpointsEditField
        function BreakpointsEditFieldValueChanged(app, event)
            num_break_points = app.BreakpointsEditField.Value;
            if (num_break_points > 0) 
                app.positions = zeros(1, num_break_points);
                app.UITable.Visible = true;
                app.UITable.Enable = 'on';
                set(app.UITable, 'Data', zeros(num_break_points,1), 'ColumnEditable', true);
            else
                app.positions =[0];
            end
        end

        % Value changed function: SamplingrateEditField
        function SamplingrateEditFieldValueChanged(app, event)
            app.samplingFrequency = app.SamplingrateEditField.Value;
        end

        % Value changed function: StarttimeEditField
        function StarttimeEditFieldValueChanged(app, event)
            app.startTime = app.StarttimeEditField.Value;
        end

        % Value changed function: EndtimeEditField
        function EndtimeEditFieldValueChanged(app, event)
            app.endTime = app.EndtimeEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SamplingrateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateEditFieldValueChanged, true);
            app.StarttimeEditFieldLabel = uilabel(app.UIFigure);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StarttimeEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimeEditFieldValueChanged, true);
            app.EndtimeEditFieldLabel = uilabel(app.UIFigure);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.EndtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndtimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndtimeEditFieldValueChanged, true);
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.BreakpointsEditFieldLabel = uilabel(app.UIFigure);
            app.BreakpointsEditFieldLabel.Text = 'Breakpoints';
            app.BreakpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BreakpointsEditField.ValueChangedFcn = createCallbackFcn(app, @BreakpointsEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Breakpoints'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.UITable.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end